classdef Spice_shop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CheckifyourearegularcustomerCheckBox  matlab.ui.control.CheckBox
        YourorderofspicegLabel      matlab.ui.control.Label
        YourorderofspicegEditField  matlab.ui.control.NumericEditField
        TheSpiceShopTextAreaLabel   matlab.ui.control.Label
        TheSpiceShopTextArea        matlab.ui.control.TextArea
        TotalcostTextAreaLabel      matlab.ui.control.Label
        TotalcostTextArea           matlab.ui.control.TextArea
        CalculatepriceButton        matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculatepriceButton
        function CalculatepriceButtonPushed(app, event)
            spice_order = app.YourorderofspicegEditField.Value
            if spice_order.Value<=25
                spice_price = 0.80;
            elseif spice_order.Value>=25
                spice_price = 0.90;
            elseif spice_order.Value>=50
                spice_price = 0.75;
            % calculates total price as 'pris' and prints it using a 'fprintf' function
            app.TotalcostTextArea.Value = (spice_order*spice_price)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CheckifyourearegularcustomerCheckBox = uicheckbox(app.UIFigure);
            app.CheckifyourearegularcustomerCheckBox.Text = 'Check if you''re a regular customer!';
            app.YourorderofspicegLabel = uilabel(app.UIFigure);
            app.YourorderofspicegLabel.Text = 'Your order of spice (g):';
            app.YourorderofspicegEditField = uieditfield(app.UIFigure, 'numeric');
            app.YourorderofspicegEditField.Value = 1;
            app.TheSpiceShopTextAreaLabel = uilabel(app.UIFigure);
            app.TheSpiceShopTextAreaLabel.Text = 'The Spice Shop';
            app.TheSpiceShopTextArea = uitextarea(app.UIFigure);
            app.TheSpiceShopTextArea.Value = {'Welcome to The Spice Shop'; 'Our spicy prices:'; 'Orders under 25g: 0.90kr/g'; 'Orders above 25g: 0.80kr/g'; 'Orders above 50g: 0.75kr/g'};
            app.TotalcostTextAreaLabel = uilabel(app.UIFigure);
            app.TotalcostTextAreaLabel.Text = 'Total cost:';
            app.TotalcostTextArea = uitextarea(app.UIFigure);
            app.CalculatepriceButton = uibutton(app.UIFigure, 'push');
            app.CalculatepriceButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatepriceButtonPushed, true);
            app.CalculatepriceButton.Text = 'Calculate price';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spice_shop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end